classdef app_GUI_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        VisibleLightCommunicationSystemCosimulationPlatformLabel  matlab.ui.control.Label
        MatlabADSCadenceCosimulationPlatformLabel  matlab.ui.control.Label
        IFFTPointNumberEditFieldLabel   matlab.ui.control.Label
        IFFTPointNumberEditField        matlab.ui.control.NumericEditField
        SNRMeasurementButton            matlab.ui.control.Button
        NumberofCPEditFieldLabel        matlab.ui.control.Label
        NumberofCPEditField             matlab.ui.control.NumericEditField
        OFDMFrameNumberEditFieldLabel   matlab.ui.control.Label
        OFDMFrameNumberEditField        matlab.ui.control.NumericEditField
        SamplingRateMSaEditFieldLabel   matlab.ui.control.Label
        SamplingRateMSaEditField        matlab.ui.control.NumericEditField
        UpsamplingRateEditFieldLabel    matlab.ui.control.Label
        UpsamplingRateEditField         matlab.ui.control.NumericEditField
        BitAllocation                   matlab.ui.control.UIAxes
        EnergyAllocation                matlab.ui.control.UIAxes
        BitandEnergyAllocationButton    matlab.ui.control.Button
        SignalModulationButton          matlab.ui.control.Button
        UIAxes_SNR                      matlab.ui.control.UIAxes
        TransmittedConstellation        matlab.ui.control.UIAxes
        UIAxes5                         matlab.ui.control.UIAxes
        SingalDemodulationButton        matlab.ui.control.Button
        PAPRdBEditFieldLabel            matlab.ui.control.Label
        PAPRdBEditField                 matlab.ui.control.NumericEditField
        BitErrorRateEditFieldLabel      matlab.ui.control.Label
        BitErrorRateEditField           matlab.ui.control.NumericEditField
        SNRMeasurementPreButton         matlab.ui.control.Button
        HighPaddingNumberEditFieldLabel  matlab.ui.control.Label
        HighPaddingNumberEditField      matlab.ui.control.NumericEditField
        LowPaddingNumberEditFieldLabel  matlab.ui.control.Label
        LowPaddingNumberEditField       matlab.ui.control.NumericEditField
        UsedSubcarrierNumberEditFieldLabel  matlab.ui.control.Label
        UsedSubcarrierNumberEditField   matlab.ui.control.NumericEditField
        NthSubcarrierConstellationButton  matlab.ui.control.Button
        ObservedSubcarrierIndexIn       matlab.ui.control.NumericEditField
        TargetSymbolErrorRateEditFieldLabel  matlab.ui.control.Label
        TargetSymbolErrorRateEditField  matlab.ui.control.NumericEditField
        ParametersLoadButton            matlab.ui.control.Button
        DataRateEditFieldLabel          matlab.ui.control.Label
        DataRateEditField               matlab.ui.control.NumericEditField
        UIAxes5_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ObservedSubcarrierIndex double % The subcarrier index you want to observe
        TimeReceived double
        DigitalReceived double
        TimeTransmitted double
        DigitalTransmitted double
        N double
        Ncp double
        SubcarrierNum double
        LowPaddingNum double
        HighPaddingNum double
        FrameNum double
        SAMPLING_RATE double
        OverSampling double
        target_ser double
        DAC_RES double
        FRACTION_LENGTH double
        TRANSMITTED_PAPR double
        BitErrorRate double
        DataRate double
        SNR_dB double
        RArray double
        SArray double
        DataSymbolQuan double
        OFDMSymbolWithoutPaddingAcc double
        subcarrierindex double
        V_LED_DRIVER_UP_SAMPLING double
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SNRMeasurementPreButton
        function SNRMeasurementPreButtonPushed(app, event)
            Transmitter_SNR_GUI;
        end

        % Button pushed function: SNRMeasurementButton
        function SNRMeasurementButtonPushed(app, event)
            [app.SNR_dB] = Receiver_SNR_GUI;
%             disp(app.SNR_dB);
%             disp(app.SubcarrierNum);
            plot(app.UIAxes_SNR,1:app.SubcarrierNum,app.SNR_dB,'LineWidth',2);
           %%
%             myImage = imread('Generated_pic/SNR_measurement.jpg');
%             set(app.UIAxes_SNR,'Units','pixels');
%             resizePos = get(app.UIAxes_SNR,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.UIAxes_SNR);
        end

        % Button pushed function: BitandEnergyAllocationButton
        function BitandEnergyAllocationButtonPushed(app, event)
            [app.RArray,app.SArray] = BAE_GUI();
            stem(app.BitAllocation,1:app.SubcarrierNum,app.RArray,'LineWidth',1);
%           for app.subcarrierindex = 1:app.SubcarrierNum
%             line([app.subcarrierindex,app.subcarrierindex],[app.RArray(app.subcarrierindex),0]);
%           end
%             xlim([0,60]);
%             ylim([0,6]);
%             set(gca, 'LineWidth', 1.5);
            stem(app.EnergyAllocation,1:app.SubcarrierNum,app.SArray,'LineWidth',1);
%            for  app.subcarrierindex = 1:app.SubcarrierNum
%             line([app.subcarrierindex,app.subcarrierindex],[app.SArray(app.subcarrierindex),0]);
%            end
%             xlim([0,60]);
%             ylim([0,3]);
%             set(gca, 'LineWidth', 1.5);
%             set(gca, 'fontsize', 16);
% set(gca, 'XMinorTick', 'on');
% set(gca, 'YMinorTick', 'on');
% set(gca, 'XGrid', 'on');
% set(gca, 'YGrid', 'on');
% set(gca, 'LineWidth', 1.5);
%             myImage = imread('Generated_pic/Bit_Allocation.jpg');
%             set(app.BitAllocation,'Units','pixels');
%             resizePos = get(app.BitAllocation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.BitAllocation);  
%             myImage = imread('Generated_pic/Energy_Allocation.jpg');
%             set(app.EnergyAllocation,'Units','pixels');
%             resizePos = get(app.EnergyAllocation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.EnergyAllocation);             
        end

        % Button pushed function: SignalModulationButton
        function SignalModulationButtonPushed(app, event)
            [app.DataSymbolQuan,app.TimeTransmitted,app.DigitalTransmitted,app.V_LED_DRIVER_UP_SAMPLING,app.TRANSMITTED_PAPR]=Transmitter_GUI; 
            app.PAPRdBEditField.Value = app.TRANSMITTED_PAPR;
           % plot(app.TransmittedWaveform,app.TimeTransmitted,app.DigitalTransmitted);
           %overlap with check button
%             app.ObservedSubcarrierIndex = app.ObservedSubcarrierIndexIn.Value;
%             myImage = imread(fullfile('Generated_pic/Transmitted_Constellation/',[num2str(app.ObservedSubcarrierIndex),'thSubcarrier.jpg']));
%             set(app.TransmittedConstellation,'Units','pixels');
%             resizePos = get(app.TransmittedConstellation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.TransmittedConstellation);  
            %add signal spectrum figure
            app.OverSampling =  app.UpsamplingRateEditField.Value;
            app.SAMPLING_RATE = app.SamplingRateMSaEditField.Value;
            FS=app.SAMPLING_RATE*app.OverSampling;
            [p,f1] = pspectrum(real(app.V_LED_DRIVER_UP_SAMPLING),FS);
             p = 10*log10(p); 
             plot(app.UIAxes5_2,f1,p);
%            nfft = 2^(nextpow2(length(app.DigitalTransmitted))-2)/4;
%            win = window(@hann,nfft);
%            olp = nfft/2;
%            [xx, f1] = pwelch(app.DigitalTransmitted,win,olp,nfft,app.SAMPLING_RATE*app.OverSampling);
%            xx = fftshift(xx);
%            xx = 10*log10(xx);
%            max_xx = max(xx);
%            max_xx = 0-max_xx;
%            xx = xx+max_xx;
%            f = f1-max(f1)/2;
%            plot(app.UIAxes5_2,f,xx,'r');
        end

        % Button pushed function: SingalDemodulationButton
        function SingalDemodulationButtonPushed(app, event)
            [app.OFDMSymbolWithoutPaddingAcc,app.TimeReceived,app.DigitalReceived,app.DataRate,app.BitErrorRate]=Receiver_GUI;
            app.BitErrorRateEditField.Value = app.BitErrorRate;
            app.DataRateEditField.Value = app.DataRate;
       %   plot(app.ReceivedWaveform,app.TimeReceived,app.DigitalReceived);
          %overlap with check button
%             app.ObservedSubcarrierIndex = app.ObservedSubcarrierIndexIn.Value;
%             myImage = imread(fullfile('Generated_pic/Received_Constellation/',[num2str(app.ObservedSubcarrierIndex),'thSubcarrier.jpg']));
%             set(app.ReceivedConstellation,'Units','pixels');
%             resizePos = get(app.ReceivedConstellation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.ReceivedConstellation);
            %add signal spectrum figure
            app.OverSampling =  app.UpsamplingRateEditField.Value;
            app.SAMPLING_RATE = app.SamplingRateMSaEditField.Value;
            app.OverSampling =  app.UpsamplingRateEditField.Value;
            app.SAMPLING_RATE = app.SamplingRateMSaEditField.Value;
            FS=app.SAMPLING_RATE*app.OverSampling;
            [p,f1] = pspectrum(real(app.DigitalReceived),FS);
             p = 10*log10(p); 
             plot(app.UIAxes5,f1,p);
%             nfft = 2^(nextpow2(length(app.DigitalReceived))-2)/4;
%            win = window(@hann,nfft);
%            olp = nfft/2;
%            [xx, f1] = pwelch(app.DigitalReceived,win,olp,nfft,app.SAMPLING_RATE*app.OverSampling);
%            xx = fftshift(xx);
%            xx = 10*log10(xx);
%            max_xx = max(xx);
%            max_xx = 0-max_xx;
%            xx = xx+max_xx;
%            f = f1-max(f1)/2;
%            plot(app.UIAxes5,f,xx,'r'); 
        end

        % Button pushed function: NthSubcarrierConstellationButton
        function NthSubcarrierConstellationButtonPushed(app, event)
           
                   app.ObservedSubcarrierIndex = app.ObservedSubcarrierIndexIn.Value;
       
%                  fig = figure(app.ObservedSubcarrierIndex);
%                 [app.DataSymbolQuan,app.TimeTransmitted,app.DigitalTransmitted,app.TRANSMITTED_PAPR]=Transmitter_GUI;,'LineWidth',2
                 sz=100;c='r';type='s';  %c red;s square
                 scatter(app.TransmittedConstellation,real(app.OFDMSymbolWithoutPaddingAcc(app.ObservedSubcarrierIndex,:)),imag(app.OFDMSymbolWithoutPaddingAcc(app.ObservedSubcarrierIndex,:)),'+','LineWidth',2)
                 hold (app.TransmittedConstellation,'on')
                 scatter(app.TransmittedConstellation, real(app.DataSymbolQuan(app.ObservedSubcarrierIndex,:)),imag(app.DataSymbolQuan(app.ObservedSubcarrierIndex,:)),sz,c,type,'LineWidth',2)
                 hold (app.TransmittedConstellation,'off')

                 
%                  fig = figure(app.ObservedSubcarrierIndex+4);
%                  [app.OFDMSymbolWithoutPaddingAcc,app.TimeReceived,app.DigitalReceived,app.DataRate,app.BitErrorRate]=Receiver_GUI;
%                  scatter(app.TransmittedConstellation_2,real(app.OFDMSymbolWithoutPaddingAcc(app.ObservedSubcarrierIndex,:)),imag(app.OFDMSymbolWithoutPaddingAcc(app.ObservedSubcarrierIndex,:)),'+','LineWidth',2);
           
%             myImage = imread(fullfile('Generated_pic/Received_Constellation/',[num2str(app.ObservedSubcarrierIndex),'thSubcarrier.jpg']));
%             set(app.ReceivedConstellation,'Units','pixels');
%             resizePos = get(app.ReceivedConstellation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.ReceivedConstellation); 
%             myImage = imread(fullfile('Generated_pic/Transmitted_Constellation/',[num2str(app.ObservedSubcarrierIndex),'thSubcarrier.jpg']));
%             set(app.TransmittedConstellation,'Units','pixels');
%             resizePos = get(app.TransmittedConstellation,'Position');
%             myImage= imresize(myImage, [resizePos(3) NaN]);
%             imshow(myImage,'Parent',app.TransmittedConstellation);             
        end

        % Button pushed function: ParametersLoadButton
        function ParametersLoadButtonPushed(app, event)
            app.N = app.IFFTPointNumberEditField.Value;
            app.Ncp = app.NumberofCPEditField.Value;
            app.SubcarrierNum = app.UsedSubcarrierNumberEditField.Value;
            app.LowPaddingNum = app.LowPaddingNumberEditField.Value;
            app.HighPaddingNum = app.HighPaddingNumberEditField.Value;
            app.OverSampling =  app.UpsamplingRateEditField.Value;
            app.FrameNum = app.OFDMFrameNumberEditField.Value;
            app.SAMPLING_RATE = app.SamplingRateMSaEditField.Value;
            app.target_ser = app.TargetSymbolErrorRateEditField.Value;
           % app.DAC_RES = app.DACResulotionEditField.Value;
           % app.FRAC_LENGTH = app.FractionalLengthEditField.Value;
            Parameter_Load(app.N,app.Ncp,app.SubcarrierNum,app.LowPaddingNum,app.HighPaddingNum,app.FrameNum,app.SAMPLING_RATE,app.OverSampling,app.target_ser); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VisibleLightCommunicationSystemCosimulationPlatformLabel = uilabel(app.UIFigure);
            app.VisibleLightCommunicationSystemCosimulationPlatformLabel.Text = 'Visible Light Communication System Co-simulation Platform';
            app.MatlabADSCadenceCosimulationPlatformLabel = uilabel(app.UIFigure);
            app.MatlabADSCadenceCosimulationPlatformLabel.Text = 'HKUST-Qualcomm Optical Wirelss Lab (HQOWL),   Hong Kong University of Science and Technology (HKUST)';
            app.IFFTPointNumberEditFieldLabel = uilabel(app.UIFigure);
            app.IFFTPointNumberEditFieldLabel.Text = 'IFFT Point Number';
            app.IFFTPointNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.IFFTPointNumberEditField.Value = 128;
            app.SNRMeasurementButton = uibutton(app.UIFigure, 'push');
            app.SNRMeasurementButton.ButtonPushedFcn = createCallbackFcn(app, @SNRMeasurementButtonPushed, true);
            app.SNRMeasurementButton.Text = 'SNR Measurement';
            app.NumberofCPEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofCPEditFieldLabel.Text = 'Number of CP';
            app.NumberofCPEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofCPEditField.Value = 32;
            app.OFDMFrameNumberEditFieldLabel = uilabel(app.UIFigure);
            app.OFDMFrameNumberEditFieldLabel.Text = 'OFDM Frame Number';
            app.OFDMFrameNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.OFDMFrameNumberEditField.Value = 100;
            app.SamplingRateMSaEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateMSaEditFieldLabel.Text = 'Sampling Rate (MSa)';
            app.SamplingRateMSaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplingRateMSaEditField.Value = 150000000;
            app.UpsamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.UpsamplingRateEditFieldLabel.Text = 'Up-sampling Rate';
            app.UpsamplingRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpsamplingRateEditField.Value = 4;
            app.BitAllocation = uiaxes(app.UIFigure);
            title(app.BitAllocation, 'Bit Loading Result')
            xlabel(app.BitAllocation, 'Index of Subcarrier')
            ylabel(app.BitAllocation, 'Number of Bit')
            app.EnergyAllocation = uiaxes(app.UIFigure);
            title(app.EnergyAllocation, 'Energy Loading  Result')
            xlabel(app.EnergyAllocation, 'Index of Subcarrier')
            ylabel(app.EnergyAllocation, 'Energy Scaling Factor')
            app.BitandEnergyAllocationButton = uibutton(app.UIFigure, 'push');
            app.BitandEnergyAllocationButton.ButtonPushedFcn = createCallbackFcn(app, @BitandEnergyAllocationButtonPushed, true);
            app.BitandEnergyAllocationButton.Text = 'Bit and Energy Allocation';
            app.SignalModulationButton = uibutton(app.UIFigure, 'push');
            app.SignalModulationButton.ButtonPushedFcn = createCallbackFcn(app, @SignalModulationButtonPushed, true);
            app.SignalModulationButton.Text = 'Signal Modulation';
            app.UIAxes_SNR = uiaxes(app.UIFigure);
            title(app.UIAxes_SNR, 'SNR Measurement')
            xlabel(app.UIAxes_SNR, 'Index of Subcarrier')
            ylabel(app.UIAxes_SNR, 'SNR(dB) ')
            app.TransmittedConstellation = uiaxes(app.UIFigure);
            title(app.TransmittedConstellation, 'Nth Subcarrier Constellation')
            xlabel(app.TransmittedConstellation, 'In Phase')
            ylabel(app.TransmittedConstellation, 'Quadrature')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Received Signal Spectrum')
            xlabel(app.UIAxes5, 'Frequency (Hz)')
            ylabel(app.UIAxes5, 'Received Power (dB)')
            app.SingalDemodulationButton = uibutton(app.UIFigure, 'push');
            app.SingalDemodulationButton.ButtonPushedFcn = createCallbackFcn(app, @SingalDemodulationButtonPushed, true);
            app.SingalDemodulationButton.Text = 'Singal Demodulation';
            app.PAPRdBEditFieldLabel = uilabel(app.UIFigure);
            app.PAPRdBEditFieldLabel.Text = 'PAPR (dB)';
            app.PAPRdBEditField = uieditfield(app.UIFigure, 'numeric');
            app.PAPRdBEditField.Value = 1;
            app.BitErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.BitErrorRateEditFieldLabel.Text = 'Bit Error Rate';
            app.BitErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.SNRMeasurementPreButton = uibutton(app.UIFigure, 'push');
            app.SNRMeasurementPreButton.ButtonPushedFcn = createCallbackFcn(app, @SNRMeasurementPreButtonPushed, true);
            app.SNRMeasurementPreButton.Text = 'SNR Measurement Pre';
            app.HighPaddingNumberEditFieldLabel = uilabel(app.UIFigure);
            app.HighPaddingNumberEditFieldLabel.Text = 'High Padding Number';
            app.HighPaddingNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowPaddingNumberEditFieldLabel = uilabel(app.UIFigure);
            app.LowPaddingNumberEditFieldLabel.Text = 'Low Padding Number';
            app.LowPaddingNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsedSubcarrierNumberEditFieldLabel = uilabel(app.UIFigure);
            app.UsedSubcarrierNumberEditFieldLabel.Text = 'Used Subcarrier Number';
            app.UsedSubcarrierNumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.UsedSubcarrierNumberEditField.Value = 63;
            app.NthSubcarrierConstellationButton = uibutton(app.UIFigure, 'push');
            app.NthSubcarrierConstellationButton.ButtonPushedFcn = createCallbackFcn(app, @NthSubcarrierConstellationButtonPushed, true);
            app.NthSubcarrierConstellationButton.Text = 'Nth Subcarrier Constellation';
            app.ObservedSubcarrierIndexIn = uieditfield(app.UIFigure, 'numeric');
            app.ObservedSubcarrierIndexIn.Value = 1;
            app.TargetSymbolErrorRateEditFieldLabel = uilabel(app.UIFigure);
            app.TargetSymbolErrorRateEditFieldLabel.Text = 'Target Symbol Error Rate';
            app.TargetSymbolErrorRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetSymbolErrorRateEditField.Value = 0.001;
            app.ParametersLoadButton = uibutton(app.UIFigure, 'push');
            app.ParametersLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersLoadButtonPushed, true);
            app.ParametersLoadButton.Text = 'Parameters Load';
            app.DataRateEditFieldLabel = uilabel(app.UIFigure);
            app.DataRateEditFieldLabel.Text = 'Data Rate';
            app.DataRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.UIAxes5_2 = uiaxes(app.UIFigure);
            title(app.UIAxes5_2, 'Transmitted Signal Spectrum')
            xlabel(app.UIAxes5_2, 'Frequency (Hz)')
            ylabel(app.UIAxes5_2, 'Transmitted Power (dB)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_GUI_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end